classdef smBEVOapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGridLayout                 matlab.ui.container.GridLayout
        MainControlsGridLayout         matlab.ui.container.GridLayout
        HelpButton                     matlab.ui.control.Button
        MessageLabel                   matlab.ui.control.Label
        MinLevelSepdeltaYEditFieldLabel  matlab.ui.control.Label
        MinLevelSepdeltaYEditField     matlab.ui.control.NumericEditField
        SmoothinglambdaEditField       matlab.ui.control.NumericEditField
        SmoothinglambdaEditFieldLabel  matlab.ui.control.Label
        CopyPlotsButton                matlab.ui.control.Button
        ShowIdealizedCheckBox          matlab.ui.control.CheckBox
        ShowLevelsCheckBox             matlab.ui.control.CheckBox
        BaselineSubtractedCheckBox     matlab.ui.control.CheckBox
        ImagePanel                     matlab.ui.container.Panel
        ImagePanelGridLayout           matlab.ui.container.GridLayout
        UpdateImageButton              matlab.ui.control.Button
        pxsigmaYEditFieldLabel         matlab.ui.control.Label
        pxPerSigmaYEditField           matlab.ui.control.NumericEditField
        pxsigmaXLabel                  matlab.ui.control.Label
        pxPerSigmaXEditField           matlab.ui.control.NumericEditField
        sigmaYEditFieldLabel           matlab.ui.control.Label
        sigmaYEditField                matlab.ui.control.NumericEditField
        sigmaXEditFieldLabel           matlab.ui.control.Label
        sigmaXEditField                matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        MaxItersEditFieldLabel         matlab.ui.control.Label
        SnakeMaxItersEditField         matlab.ui.control.NumericEditField
        gammaLabel                     matlab.ui.control.Label
        gammaEditField                 matlab.ui.control.NumericEditField
        betaLabel                      matlab.ui.control.Label
        betaEditField                  matlab.ui.control.NumericEditField
        alphaLabel                     matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        SnakeLevelRefinementCheckBox   matlab.ui.control.CheckBox
        SeriesGridLayout               matlab.ui.container.GridLayout
        SeriesSpinner                  matlab.ui.control.Spinner
        SeriesSpinnerLabel             matlab.ui.control.Label
        AbortButton                    matlab.ui.control.Button
        SaveDataButton                 matlab.ui.control.Button
        RunAllButton                   matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        LoadDataButton                 matlab.ui.control.Button
        DataUIAxes                     matlab.ui.control.UIAxes
        ImageUIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DataSeries = struct.empty % struct array of data series and associated baselining
        abort = false; % abort current run
    end
    
    properties (Access = private)
        isBusy = false % prevent multiple simultaneous runs
    end
    
    methods (Access = public)
        
        function loadData(app, data)
            if ~exist('data', 'var') || isempty(data)
                [file, path] = uigetfile('*.mat', 'Open data file.');
                drawnow;
                figure(app.UIFigure);
                if isequal(file, 0)
                    return
                end
                filepath = fullfile(path, file);
                wb = waitbar(0, 'Loading data from file...');
                tmp = load(filepath);
                close(wb);
                if isfield(tmp, 'data')
                    app.loadData(tmp.data);
                else
                    data = struct2cell(tmp);
                    app.loadData(data{1});
                end
                return
            elseif isstring(data)
                try
                    wb = waitbar(0, 'Loading data from file...');
                    tmp = load(data);
                    close(wb);
                    if isfield(tmp, 'data')
                        app.loadData(tmp.data);
                    else
                        data = struct2cell(tmp);
                        app.loadData(data{1});
                    end
                    return
                catch err
                    disp(err);
                end
            elseif isstruct(data)
                % assume data has the right format
                app.DataSeries = data;
            elseif iscell(data)
                % cell array of [x y] data
                app.DataSeries = struct.empty;
                for i = 1:numel(data)
                    xy = data{i};
                    if iscolumn(xy) || isrow(xy)
                        app.DataSeries(end+1).ydata = reshape(xy, [], 1);
                        app.DataSeries(end).xdata = reshape(1:length(xy), [], 1);
                    else
                        if size(xy,1) == 2 && size(xy,2) > 2
                            % [x; y] --> [x y]
                            xy = xy';
                        end
                        % [x y1 y2 ...]
                        x = xy(:,1);
                        for j = 2:size(xy,2)
                            app.DataSeries(end+1).ydata = xy(:,j);
                            app.DataSeries(end).xdata = x;
                        end
                    end
                end
            else
                % [x y] data
                app.DataSeries = struct.empty;
                xy = data;
                if iscolumn(xy) || isrow(xy)
                    app.DataSeries(end+1).ydata = reshape(xy, [], 1);
                    app.DataSeries(end).xdata = reshape(1:length(xy), [], 1);
                else
                    if size(xy,1) == 2 && size(xy,2) > 2
                        % [x; y] --> [x y]
                        xy = xy';
                    end
                    % [x y1 y2 ...]
                    x = xy(:,1);
                    for j = 2:size(xy,2)
                        app.DataSeries(end+1).ydata = xy(:,j);
                        app.DataSeries(end).xdata = x;
                    end
                end
            end
            app.SeriesSpinner.Limits = [0, numel(app.DataSeries)];
            app.SeriesSpinner.Value = 1;
            if numel(app.DataSeries) == 1
                app.SeriesSpinner.Visible = 'off';
                app.SeriesSpinnerLabel.Visible = 'off';
                app.RunAllButton.Visible = 'off';
            else
                app.SeriesSpinner.Visible = 'on';
                app.SeriesSpinnerLabel.Visible = 'on';
                app.RunAllButton.Visible = 'on';
            end
            app.showDataSeries(1);
        end
        
        function saveData(app, filepath)
            if ~exist('filepath', 'var') || isempty(filepath)
                [file, path] = uiputfile('*.mat', 'Save data to file.');
                drawnow;
                figure(app.UIFigure);
                if isequal(file, 0)
                    return
                end
                filepath = fullfile(path, file);
            end
            data = app.DataSeries;
            wb = waitbar(0, 'Saving data to file...');
            save(filepath, 'data');
            close(wb);
        end
        
        function params = getParams(app)
            params.sigmaX = app.sigmaXEditField.Value;
            params.sigmaY = app.sigmaYEditField.Value;
            params.pixelsPerSigmaX = app.pxPerSigmaXEditField.Value;
            params.pixelsPerSigmaY = app.pxPerSigmaYEditField.Value;
            params.smoothing = app.SmoothinglambdaEditField.Value;
            params.minLevelSep = app.MinLevelSepdeltaYEditField.Value;
            params.snakeLevelRefinement = app.SnakeLevelRefinementCheckBox.Value;
            params.alpha = app.alphaEditField.Value;
            params.beta = app.betaEditField.Value;
            params.gamma = app.gammaEditField.Value;
            params.maxSnakeIter = app.SnakeMaxItersEditField.Value;
        end
        
        function setParams(app, params)
            app.sigmaXEditField.Value = params.sigmaX;
            app.sigmaYEditField.Value = params.sigmaY;
            app.pxPerSigmaXEditField.Value = params.pixelsPerSigmaX;
            app.pxPerSigmaYEditField.Value = params.pixelsPerSigmaY;
            app.SmoothinglambdaEditField.Value = params.smoothing;
            app.MinLevelSepdeltaYEditField.Value = params.minLevelSep;
            app.SnakeLevelRefinementCheckBox.Value = params.snakeLevelRefinement;
            app.alphaEditField.Value = params.alpha;
            app.betaEditField.Value = params.beta;
            app.gammaEditField.Value = params.gamma;
            app.MaxSnakeItersSpinner.Value = params.maxSnakeIter;
        end
        
        function showDataSeries(app, i, im)
            if ~exist('i', 'var')
                i = app.SeriesSpinner.Value;
            else
                app.SeriesSpinner.Value = i;
            end
            S = app.DataSeries(i);
            hasBaseline = isfield(S, 'ybaseline') && isequal(size(S.ybaseline), size(S.ydata));
            hasLevels = isfield(S, 'ylevelOffsets') && ~isempty(S.ylevelOffsets);
            hasStates = isfield(S, 'ystates') && isequal(size(S.ystates), size(S.ydata));
            showBaselineSubtracted = app.BaselineSubtractedCheckBox.Value && hasBaseline;
            showLevels = app.ShowLevelsCheckBox.Value && hasLevels;
            showIdeal = app.ShowIdealizedCheckBox.Value && hasStates;
            if showIdeal
                yideal = zeros(size(S.ydata));
                states = unique(S.ystates);
                for k = 1:numel(states)
                    yideal(S.ystates == states(k)) = S.ylevelOffsets(k);
                end
            end
            % plot data series
            if showBaselineSubtracted
                hold(app.DataUIAxes, 'off');
                plot(app.DataUIAxes, S.xdata, S.ydata - S.ybaseline);
                hold(app.DataUIAxes, 'on');
                if showLevels
                    plot(app.DataUIAxes, S.xdata([1,end]), repmat(S.ylevelOffsets, 2, 1), 'k--');
                end
                if showIdeal
                    plot(app.DataUIAxes, S.xdata, yideal, 'r-', 'linewidth', 2);
                end
            else
                hold(app.DataUIAxes, 'off');
                plot(app.DataUIAxes, S.xdata, S.ydata);
                hold(app.DataUIAxes, 'on');
                if showLevels
                    plot(app.DataUIAxes, S.xdata, S.ybaseline + S.ylevelOffsets, 'k--');
                end
                if showIdeal
                    plot(app.DataUIAxes, S.xdata, S.ybaseline + yideal, 'r-', 'linewidth', 2);
                end
            end
            axis(app.DataUIAxes, 'tight');
            % plot image representation of data series
            if ~exist('im', 'var')
                try
                    result = app.getResult(S, S.params, true);
                    im = result.im;
                    colXCenters = result.colXCenters;
                    rowYCenters = result.rowYCenters;
                catch
                    im = [];
                end
            else
                colXCenters = S.colXCenters;
                rowYCenters = S.rowYCenters;
            end
            if exist('im', 'var') && ~isempty(im)
                hold(app.ImageUIAxes, 'off');
                imagesc(app.ImageUIAxes, colXCenters, rowYCenters, im);
                colormap(app.ImageUIAxes, gray(256));
                axis(app.ImageUIAxes, 'xy');
                hold(app.ImageUIAxes, 'on');
                if showLevels
                    plot(app.ImageUIAxes, S.xdata, S.ybaseline + S.ylevelOffsets, 'g-', 'linewidth', 1);
                end
                if showIdeal
                    plot(app.ImageUIAxes, S.xdata, S.ybaseline + yideal, 'r-', 'linewidth', 2);
                end
            else
                cla(app.ImageUIAxes);
            end
            axis(app.ImageUIAxes, 'tight');
        end
        
        function result = getResult(app, series, params, imageOnly)
            if ~exist('imageOnly', 'var')
                imageOnly = false;
            end
            result = smBEVO([series.xdata, series.ydata], params.sigmaX, params.sigmaY, ...
                'pixelsPerSigmaX', params.pixelsPerSigmaX, ...
                'pixelsPerSigmaY', params.pixelsPerSigmaY, ...
                'smoothing', params.smoothing, ...
                'minLevelSep', params.minLevelSep, ...
                'snakeLevelRefinement', params.snakeLevelRefinement, ...
                'alpha', params.alpha, ...
                'beta', params.beta, ...
                'gamma', params.gamma, ...
                'maxSnakeIter', params.maxSnakeIter, ...
                'app', app, ...
                'getFilteredImageOnly', imageOnly);
        end
        
        function updateImage(app)
            try
                app.isBusy = true;
                app.abort = false;
                app.MessageLabel.Text = "Updating image...";
                drawnow;
                p = app.getParams();
                i = app.SeriesSpinner.Value;
                S = app.DataSeries(i);
                tic;
                result = app.getResult(S, p, true);
                telapsed = toc;
                if app.abort
                    app.MessageLabel.Text = sprintf("Aborted after %.3f sec", telapsed);
                else
                    app.MessageLabel.Text = sprintf("Finished in %.3f sec", telapsed);
                    hold(app.ImageUIAxes, 'off');
                    imagesc(app.ImageUIAxes, result.colXCenters, result.rowYCenters, result.im);
                    colormap(app.ImageUIAxes, gray(256));
                    axis(app.ImageUIAxes, 'xy');
                    axis(app.ImageUIAxes, 'tight');
                end
                app.isBusy = false;
                app.abort = false;
            catch err
                disp(err);
                app.isBusy = false;
                app.abort = false;
                uialert(app.UIFigure, err.message, "Error");
            end
        end
        
        function run(app)
            if isempty(app.DataSeries) || app.isBusy
                return
            end
            try
                app.isBusy = true;
                app.abort = false;
                app.MessageLabel.Text = "Running...";
                drawnow;
                p = app.getParams();
                i = app.SeriesSpinner.Value;
                S = app.DataSeries(i);
                tic;
                result = app.getResult(S, p);
                telapsed = toc;
                if app.abort
                    app.MessageLabel.Text = sprintf("Aborted after %.3f sec", telapsed);
                else
                    app.DataSeries(i).params = p;
                    fields = fieldnames(result);
                    for k = 1:numel(fields)
                        if fields{k} ~= "im"
                            app.DataSeries(i).(fields{k}) = result.(fields{k});
                        end
                    end
                    app.MessageLabel.Text = sprintf("Finished in %.3f sec", telapsed);
                    app.showDataSeries(i, result.im);
                end
                app.isBusy = false;
                app.abort = false;
            catch err
                disp(err);
                app.isBusy = false;
                app.abort = false;
                uialert(app.UIFigure, err.message, "Error");
            end
        end
        
        function runAll(app)
            if isempty(app.DataSeries) || app.isBusy
                return
            end
            nseries = numel(app.DataSeries);
            if nseries == 1
                app.run();
                return
            end
            try
                app.isBusy = true;
                app.abort = false;
                app.MessageLabel.Text = sprintf("Running %d/%d...", 1, nseries);
                drawnow;
                p = app.getParams();
                j = app.SeriesSpinner.Value;
                tic;
                for i = 1:nseries
                    app.MessageLabel.Text = sprintf("Running %d/%d...", i, nseries);
                    drawnow limitrate;
                    S = app.DataSeries(i);
                    result = app.getResult(S, p);
                    if app.abort
                        break
                    end
                    app.DataSeries(i).params = p;
                    fields = fieldnames(result);
                    for k = 1:numel(fields)
                        if fields{k} ~= "im"
                            app.DataSeries(i).(fields{k}) = result.(fields{k});
                        end
                    end
                    if i == j
                        im = result.im;
                    end
                end
                telapsed = toc;
                if app.abort
                    app.MessageLabel.Text = sprintf("Aborted after %.3f sec", telapsed);
                else
                    app.MessageLabel.Text = sprintf("Finished in %.3f sec", telapsed);
                    app.showDataSeries(j, im);
                end
                app.isBusy = false;
                app.abort = false;
            catch err
                disp(err);
                app.isBusy = false;
                app.abort = false;
                uialert(app.UIFigure, err.message, "Error");
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % axes options
            axis(app.DataUIAxes, 'tight');
            axis(app.ImageUIAxes, 'tight');
             
            % more performant axes interactions?
            app.DataUIAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            app.ImageUIAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];

            % linkaxes data and image axes
%             try
%                 linkaxes([app.DataUIAxes, app.ImageUIAxes], 'xy');
%             catch
%                 warning("Update to MATLAB version 2020b or later for linked axes.");
%             end

            % check for splinefit add-on
            if exist('splinefit.m', 'file') ~= 2
                uialert(app.UIFigure, ...
                    "Spline smoothing requires the 'splinefit' Add-On by Jonas Lundgren. It is available via MATLAB's Add-On Explorer.", ...
                    "Missing splinefit Add-On");
            end
            
            % citations
            %uialert(app.UIFigure, "If you use this software for a publication, please cite ???.", "Citations");
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.loadData();
            uialert(app.UIFigure, "Set sigmaX and sigmaY before running.", "Reminder");
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.saveData();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.run();
        end

        % Button pushed function: RunAllButton
        function RunAllButtonPushed(app, event)
            app.runAll();
        end

        % Value changed function: BaselineSubtractedCheckBox
        function BaselineSubtractedCheckBoxValueChanged(app, event)
            app.showDataSeries();
        end

        % Value changed function: ShowIdealizedCheckBox
        function ShowIdealizedCheckBoxValueChanged(app, event)
            app.showDataSeries();
        end

        % Value changed function: SeriesSpinner
        function SeriesSpinnerValueChanged(app, event)
            i = app.SeriesSpinner.Value;
            app.showDataSeries(i);
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.abort = true;
            disp(app.abort);
        end

        % Button pushed function: UpdateImageButton
        function UpdateImageButtonPushed(app, event)
            app.updateImage();
        end

        % Value changed function: ShowLevelsCheckBox
        function ShowLevelsCheckBoxValueChanged(app, event)
            app.showDataSeries();
        end

        % Button pushed function: CopyPlotsButton
        function CopyPlotsButtonPushed(app, event)
            fig = figure;
            copyobj(app.DataUIAxes, fig);
            figure(fig);
            ax = gca;
            ax.Units = 'normalized';
            ax.Position = [0, 0, 1, 1];
            fig = figure;
            copyobj(app.ImageUIAxes, fig);
            figure(fig);
            ax = gca;
            ax.Units = 'normalized';
            ax.Position = [0, 0, 1, 1];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'smBEVO';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.ImageUIAxes = uiaxes(app.MainGridLayout);
            app.DataUIAxes = uiaxes(app.MainGridLayout);
            app.MainControlsGridLayout = uigridlayout(app.MainGridLayout);
            app.LoadDataButton = uibutton(app.MainControlsGridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Load x,y data series from file.'};
            app.LoadDataButton.Text = 'Load Data';
            app.RunButton = uibutton(app.MainControlsGridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RunAllButton = uibutton(app.MainControlsGridLayout, 'push');
            app.RunAllButton.ButtonPushedFcn = createCallbackFcn(app, @RunAllButtonPushed, true);
            app.RunAllButton.Text = 'Run All';
            app.SaveDataButton = uibutton(app.MainControlsGridLayout, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Save data series and associated analysis to file.'};
            app.SaveDataButton.Text = 'Save Data';
            app.AbortButton = uibutton(app.MainControlsGridLayout, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.SeriesGridLayout = uigridlayout(app.MainControlsGridLayout);
            app.SeriesSpinnerLabel = uilabel(app.SeriesGridLayout);
            app.SeriesSpinnerLabel.Text = 'Series';
            app.SeriesSpinner = uispinner(app.SeriesGridLayout);
            app.SeriesSpinner.LowerLimitInclusive = 'off';
            app.SeriesSpinner.Limits = [0 Inf];
            app.SeriesSpinner.RoundFractionalValues = 'on';
            app.SeriesSpinner.ValueDisplayFormat = '%.0f';
            app.SeriesSpinner.ValueChangedFcn = createCallbackFcn(app, @SeriesSpinnerValueChanged, true);
            app.SeriesSpinner.Tooltip = {'Cycle through multiple x,y data series.'};
            app.SeriesSpinner.Value = 1;
            app.Panel = uipanel(app.MainControlsGridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.SnakeLevelRefinementCheckBox = uicheckbox(app.GridLayout2);
            app.SnakeLevelRefinementCheckBox.Tooltip = {'Enable/Disable level refinement using active contour snakes.'};
            app.SnakeLevelRefinementCheckBox.Text = 'Snake Level Refinement';
            app.alphaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.alphaEditField.UpperLimitInclusive = 'off';
            app.alphaEditField.Limits = [0 Inf];
            app.alphaEditField.Tooltip = {'Contraint on 1st derivative (elasticity) of levels (0 = none)'};
            app.alphaEditField.Value = 1;
            app.alphaLabel = uilabel(app.GridLayout2);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha$';
            app.betaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.betaEditField.UpperLimitInclusive = 'off';
            app.betaEditField.Limits = [0 Inf];
            app.betaEditField.Tooltip = {'Constraint on 2nd derivative (curvature) of levels (0 = none)'};
            app.betaEditField.Value = 1;
            app.betaLabel = uilabel(app.GridLayout2);
            app.betaLabel.Interpreter = 'latex';
            app.betaLabel.Text = '$\beta$';
            app.gammaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.gammaEditField.LowerLimitInclusive = 'off';
            app.gammaEditField.UpperLimitInclusive = 'off';
            app.gammaEditField.Limits = [0 Inf];
            app.gammaEditField.Tooltip = {'Image gradient forcefield scaling factor (typically 1)'};
            app.gammaEditField.Value = 1;
            app.gammaLabel = uilabel(app.GridLayout2);
            app.gammaLabel.Interpreter = 'latex';
            app.gammaLabel.Text = '$\gamma$';
            app.SnakeMaxItersEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SnakeMaxItersEditField.LowerLimitInclusive = 'off';
            app.SnakeMaxItersEditField.UpperLimitInclusive = 'off';
            app.SnakeMaxItersEditField.Limits = [0 Inf];
            app.SnakeMaxItersEditField.RoundFractionalValues = 'on';
            app.SnakeMaxItersEditField.ValueDisplayFormat = '%.0f';
            app.SnakeMaxItersEditField.Tooltip = {'Max number of snake iterations'};
            app.SnakeMaxItersEditField.Value = 50;
            app.MaxItersEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxItersEditFieldLabel.Text = 'Max Iters';
            app.ImagePanel = uipanel(app.MainControlsGridLayout);
            app.ImagePanelGridLayout = uigridlayout(app.ImagePanel);
            app.sigmaXEditField = uieditfield(app.ImagePanelGridLayout, 'numeric');
            app.sigmaXEditField.LowerLimitInclusive = 'off';
            app.sigmaXEditField.UpperLimitInclusive = 'off';
            app.sigmaXEditField.Limits = [0 Inf];
            app.sigmaXEditField.Tooltip = {'Image filter standard deviation along X-axis'};
            app.sigmaXEditField.Value = 1;
            app.sigmaXEditFieldLabel = uilabel(app.ImagePanelGridLayout);
            app.sigmaXEditFieldLabel.Interpreter = 'latex';
            app.sigmaXEditFieldLabel.Text = '$\sigma X$';
            app.sigmaYEditField = uieditfield(app.ImagePanelGridLayout, 'numeric');
            app.sigmaYEditField.LowerLimitInclusive = 'off';
            app.sigmaYEditField.UpperLimitInclusive = 'off';
            app.sigmaYEditField.Limits = [0 Inf];
            app.sigmaYEditField.Tooltip = {'Image filter standard deviation along Y-axis'};
            app.sigmaYEditField.Value = 1;
            app.sigmaYEditFieldLabel = uilabel(app.ImagePanelGridLayout);
            app.sigmaYEditFieldLabel.Interpreter = 'latex';
            app.sigmaYEditFieldLabel.Text = '$\sigma Y$';
            app.pxPerSigmaXEditField = uieditfield(app.ImagePanelGridLayout, 'numeric');
            app.pxPerSigmaXEditField.LowerLimitInclusive = 'off';
            app.pxPerSigmaXEditField.UpperLimitInclusive = 'off';
            app.pxPerSigmaXEditField.Limits = [0 Inf];
            app.pxPerSigmaXEditField.Tooltip = {'Pixels per sigma X (typically 4-10)'};
            app.pxPerSigmaXEditField.Value = 4;
            app.pxsigmaXLabel = uilabel(app.ImagePanelGridLayout);
            app.pxsigmaXLabel.Interpreter = 'latex';
            app.pxsigmaXLabel.Text = 'px $/ \sigma X$';
            app.pxPerSigmaYEditField = uieditfield(app.ImagePanelGridLayout, 'numeric');
            app.pxPerSigmaYEditField.LowerLimitInclusive = 'off';
            app.pxPerSigmaYEditField.UpperLimitInclusive = 'off';
            app.pxPerSigmaYEditField.Limits = [0 Inf];
            app.pxPerSigmaYEditField.Tooltip = {'Pixels per sigma Y (typically 4-10)'};
            app.pxPerSigmaYEditField.Value = 4;
            app.pxsigmaYEditFieldLabel = uilabel(app.ImagePanelGridLayout);
            app.pxsigmaYEditFieldLabel.Interpreter = 'latex';
            app.pxsigmaYEditFieldLabel.Text = 'px $/ \sigma Y$';
            app.UpdateImageButton = uibutton(app.ImagePanelGridLayout, 'push');
            app.UpdateImageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageButtonPushed, true);
            app.UpdateImageButton.Text = 'Update Image';
            app.BaselineSubtractedCheckBox = uicheckbox(app.MainControlsGridLayout);
            app.BaselineSubtractedCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineSubtractedCheckBoxValueChanged, true);
            app.BaselineSubtractedCheckBox.Tooltip = {'Show baseline subtracted data series.'};
            app.BaselineSubtractedCheckBox.Text = 'Baseline Subtracted';
            app.BaselineSubtractedCheckBox.Value = true;
            app.ShowLevelsCheckBox = uicheckbox(app.MainControlsGridLayout);
            app.ShowLevelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLevelsCheckBoxValueChanged, true);
            app.ShowLevelsCheckBox.Text = 'Show Levels';
            app.ShowLevelsCheckBox.Value = true;
            app.ShowIdealizedCheckBox = uicheckbox(app.MainControlsGridLayout);
            app.ShowIdealizedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowIdealizedCheckBoxValueChanged, true);
            app.ShowIdealizedCheckBox.Tooltip = {'Show image-based idealized data series.'};
            app.ShowIdealizedCheckBox.Text = 'Show Idealized';
            app.CopyPlotsButton = uibutton(app.MainControlsGridLayout, 'push');
            app.CopyPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPlotsButtonPushed, true);
            app.CopyPlotsButton.Tooltip = {'Copy plot axes into new Figure for saving graphics.'};
            app.CopyPlotsButton.Text = 'Copy Plots';
            app.SmoothinglambdaEditFieldLabel = uilabel(app.MainControlsGridLayout);
            app.SmoothinglambdaEditFieldLabel.Interpreter = 'latex';
            app.SmoothinglambdaEditFieldLabel.Text = 'Smoothing $\lambda$';
            app.SmoothinglambdaEditField = uieditfield(app.MainControlsGridLayout, 'numeric');
            app.SmoothinglambdaEditField.UpperLimitInclusive = 'off';
            app.SmoothinglambdaEditField.Limits = [0 Inf];
            app.SmoothinglambdaEditField.Tooltip = {'Amount of spline smoothing to apply to image segments (0 = none)'};
            app.MinLevelSepdeltaYEditField = uieditfield(app.MainControlsGridLayout, 'numeric');
            app.MinLevelSepdeltaYEditField.UpperLimitInclusive = 'off';
            app.MinLevelSepdeltaYEditField.Limits = [0 Inf];
            app.MinLevelSepdeltaYEditField.Tooltip = {'Minimum level separation (0 -> use default)'};
            app.MinLevelSepdeltaYEditFieldLabel = uilabel(app.MainControlsGridLayout);
            app.MinLevelSepdeltaYEditFieldLabel.Interpreter = 'latex';
            app.MinLevelSepdeltaYEditFieldLabel.Text = 'Min Level Sep $\delta Y$';
            app.MessageLabel = uilabel(app.MainControlsGridLayout);
            app.MessageLabel.Text = 'v1.0';
            app.HelpButton = uibutton(app.MainControlsGridLayout, 'push');
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = smBEVOapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end